classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StimulusSwitchLabel            matlab.ui.control.Label
        StimulusSwitch                 matlab.ui.control.Switch
        UIAxes                         matlab.ui.control.UIAxes
        BowdenBiomedicalOpticsLaboratoryLabel  matlab.ui.control.Label
        StreamDataSwitchLabel          matlab.ui.control.Label
        StreamDataSwitch               matlab.ui.control.Switch
        ChannelSelectionDropDownLabel  matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        
        % Mark whether there is new data
        new_data = 0;
        
        % Stimulus on or off
        stimulus = 0;
           
        % Initialize variables to temporarily store data for plotting
        time_data = [];
        time_array = [];
        ch1R_array = [];
        ch1IR_array = [];
        ch2R_array = [];
        ch2IR_array = [];
        ch3R_array = [];
        ch3IR_array = [];
        ch4R_array = [];
        ch4IR_array = [];
        ch5R_array = [];
        ch5IR_array = [];
        ch6R_array = [];
        ch6IR_array = [];
        ch7R_array = [];
        ch7IR_array = [];
        ch8R_array = [];
        ch8IR_array = [];
        ch9R_array = [];
        ch9IR_array = [];
        ch10R_array = [];
        ch10IR_array = [];
        ch11R_array = [];
        ch11IR_array = [];
        ch12R_array = [];
        ch12IR_array = [];
        ch13R_array = [];
        ch13IR_array = [];
        phighlight = [];
        nhighlight = [];
        
        % Initialize variables to temporarily store data for plotting
        dHb = [];
        dHbO2 = [];
        
        % Bluetooth variables
        b = Bluetooth('RNBT-602E',1);
        
    end
    
    properties (Access = private)
        plottingTimer; % Variable for defining timer object
    end
        
    methods (Access = public)
        
        function updatePlot(app, obj, event, string_arg)
        
             if (app.new_data == 1 && strcmp(app.StreamDataSwitch.Value, 'On'))
                
                try

                    % Plot Data and Highlights
                    if isempty(get(app.UIAxes, 'UserData'))
                        plot(app.UIAxes, app.time_data, app.dHbO2, 'r', app.time_data, app.dHb, 'k-','linewidth', 2);
                        disp('main lines plotted')
                        hold(app.UIAxes, 'on');
                        if length(app.time_data) == length(app.phighlight)
                          plot(app.UIAxes, app.time_data, app.phighlight, 'k', 'linewidth', 5);
                        else
                          plot(app.UIAxes, app.time_data, app.phighlight(1:length(app.time_data)), 'k', 'linewidth', 5);
                        end
                        hold(app.UIAxes, 'off')
                        disp('highlighter plotted')
                        set(app.UIAxes,'Xlim',[min(app.time_data), max(app.time_data)+2])
                        set(app.UIAxes,'Ylim',[-800,800])
                    else
                        set(app.UIAxes,{'xdata'},{app.time_data;app.time_data;app.time_data},{'ydata'},{app.dHbO2;app.dHb;app.phighlight});
                        set(app.UIAxes,'Xlim',[min(app.time_data), max(app.time_data)+2])
                        set(app.UIAxes,'Ylim',[-800,800])
                        disp('continued plotting done');
                    end
                    
                    % refresh plot now!
                    drawnow;
                    
                    % Reset new data indicator
                    app.new_data = 0;
                    
                catch
                    
                    disp('Plotting error');
                    
                end
                
             end
             
        end
        
        function DPF = getDPF(app, wavelength, age)
        wl = wavelength;
        A = age;
        DPF = 223.3 + 0.05624*(A^0.8493) + ...
            (-5.723 * 10^-7)*(wl^3) + (0.001245)*wl^2 + ...
            (-0.9025)*wl;
        end
        
        function signal = pipeline(app, time_data, signal, Fpass)
        
            % STEP 1: Remove moving average
            mov_avg_diviser = 3;
            moving_avg_window = length(time_data)/mov_avg_diviser;
            signal = signal - movmean(signal, moving_avg_window);
            
            % STEP 2: Low pass filter signal
            
            %fs = 3; %Hz sampling rate
            %Fpass = 0.2; %Hz low pass
            %signal = lowpass(signal, Fpass, fs);
            
            % STEP 3: Normalize data
            
            max_signal = max(signal);
            min_signal = min(signal);
            range_signal = abs(max_signal - min_signal);
            signal = (signal + abs(min_signal))/range_signal * 10;
            
            % END OF PREPROCESSING PIPELINE
            
        end
        
        function highlight = highlighter(app, time_data, br_window, amplitude, startOffset)

            highlight = ones(1,length(time_data)); %blank array for highlighter data
            highlight = highlight*amplitude;
            
            if amplitude > 0
                highlight = highlight + 2;
            else
                highlight = highlight -2;
            end
            
                for i = 1:length(time_data)
                    
                        val = time_data(i)-startOffset; %current value in time array
                        cycle = br_window * 2;
                        condition = val/cycle - floor(val/cycle);
                        if (condition <= 0.5)
                            highlight(i) = 0;
                        end
                        
                end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           disp('Inside Startup Function')
           app.plottingTimer = timer;
           app.plottingTimer.Period = 0.5;
           app.plottingTimer.ExecutionMode = 'fixedRate';
           app.plottingTimer.TimerFcn = @app.updatePlot;
           start(app.plottingTimer);
        end

        % Value changed function: StreamDataSwitch
        function StreamDataSwitchValueChanged(app, event)
            value = app.StreamDataSwitch.Value;
            
            %% Bluetooth data parsing variables
            data1 = zeros(1,3);
            count_t = 1;
            data_array = zeros(1,128);
 
            if strcmp(value, 'On')
                
                app.UIAxes.cla;
                
                disp(value);
                disp("============================================================")
                disp("Starting Bluetooth connection....")
                
                % Open Connection
                fopen(app.b);
                % Loop through eternity
                tic

                while(true)
                    
                    % Kill the loop of if hit stop
                    if strcmp(value, 'Off')
                        disp("============================================================")
                        break;
                    end
                    
                    %try % Bluetooth try / catch
                        
                    byteValue = fread(app.b, 1); % Read a byte of data
                        
                    %disp(byteValue)
                    % Extract value
                    if (byteValue == 13)
                        data1(1) = byteValue;
                        data1(2) = 0;
                        data1(3) = 0;
                    else
                        if (((data1(1) == 13) && (byteValue == 17)) || ((data1(1) == 13) && (byteValue == 11)))
                            data1(2) = byteValue;
                            data1(3) = 0;
                            if (byteValue == 17)
                                chip = 0;
                            else
                                chip = 1; % ignore anything else
                            end
                        else
                            if (((data1(1) == 13) && (data1(2) == 17) && (byteValue == 19)) || ((data1(1) == 13) && (data1(2) == 11) && (byteValue == 19)))
                                data1(3) = byteValue;
                                data1(1) = 0;
                                data1(2) = 0;
                            else
                                data1(1) = 0;
                                data1(2) = 0;
                                data1(3) = 0;
                            end
                        end
                    end
                    
                    if (data1(3) ~= 19)
                        data_array(count_t) = byteValue;
                        count_t = count_t + 1;
                        if (count_t > 128)   % Just precaution
                            count_t = 1;
                        end
                    else
                        data1(4) = 0;
                		count_t = 1;
                
                        C1R = data_array(1) + 256 * data_array(2) + 65536 * data_array(3) + 256 * 65536 * data_array(4);
                        C2R = data_array(5) + 256 * data_array(6) + 65536 * data_array(7) + 256 * 65536 * data_array(8);
                        C3R = data_array(9) + 256 * data_array(10) + 65536 * data_array(11) + 256 * 65536 * data_array(12);
                        C4R = data_array(13) + 256 * data_array(14) + 65536 * data_array(15) + 256 * 65536 * data_array(16);
                        C5R = data_array(17) + 256 * data_array(18) + 65536 * data_array(19) + 256 * 65536 * data_array(20);
                        C6R = data_array(21) + 256 * data_array(22) + 65536 * data_array(23) + 256 * 65536 * data_array(24);
                        C7R = data_array(25) + 256 * data_array(26) + 65536 * data_array(27) + 256 * 65536 * data_array(28);
                        C8R = data_array(29) + 256 * data_array(30) + 65536 * data_array(31) + 256 * 65536 * data_array(32);
                        C9R = data_array(33) + 256 * data_array(34) + 65536 * data_array(35) + 256 * 65536 * data_array(36);
                        C10R = data_array(37) + 256 * data_array(38) + 65536 * data_array(39) + 256 * 65536 * data_array(40);
                        C11R = data_array(41) + 256 * data_array(42) + 65536 * data_array(43) + 256 * 65536 * data_array(44);
                        C12R = data_array(45) + 256 * data_array(46) + 65536 * data_array(47) + 256 * 65536 * data_array(48);
                        C13R = data_array(49) + 256 * data_array(50) + 65536 * data_array(51) + 256 * 65536 * data_array(52);
                        
                        C1IR = data_array(53) + 256 * data_array(54) + 65536 * data_array(55) + 256 * 65536 * data_array(56);
                        C2IR = data_array(57) + 256 * data_array(58) + 65536 * data_array(59) + 256 * 65536 * data_array(60);
                        C3IR = data_array(61) + 256 * data_array(62) + 65536 * data_array(63) + 256 * 65536 * data_array(64);
                        C4IR = data_array(65) + 256 * data_array(66) + 65536 * data_array(67) + 256 * 65536 * data_array(68);
                        C5IR = data_array(69) + 256 * data_array(70) + 65536 * data_array(71) + 256 * 65536 * data_array(72);
                        C6IR = data_array(73) + 256 * data_array(74) + 65536 * data_array(75) + 256 * 65536 * data_array(76);
                        C7IR = data_array(77) + 256 * data_array(78) + 65536 * data_array(79) + 256 * 65536 * data_array(80);
                        C8IR = data_array(81) + 256 * data_array(82) + 65536 * data_array(83) + 256 * 65536 * data_array(84);
                        C9IR = data_array(85) + 256 * data_array(86) + 65536 * data_array(87) + 256 * 65536 * data_array(88);
                        C10IR = data_array(89) + 256 * data_array(90) + 65536 * data_array(91) + 256 * 65536 * data_array(92);
                        C11IR = data_array(93) + 256 * data_array(94) + 65536 * data_array(95) + 256 * 65536 * data_array(96);
                        C12IR = data_array(97) + 256 * data_array(98) + 65536 * data_array(99) + 256 * 65536 * data_array(100);
                        C13IR = data_array(101) + 256 * data_array(102) + 65536 * data_array(103) + 256 * 65536 * data_array(104);
                        
                        res1 = data_array(105) + 256 * data_array(106) + 65536 * data_array(107) + 256 * 65536 * data_array(108);
                        res2 = data_array(109) + 256 * data_array(110) + 65536 * data_array(111) + 256 * 65536 * data_array(112);
                        res3 = data_array(113) + 256 * data_array(114) + 65536 * data_array(115) + 256 * 65536 * data_array(116);
                        res4 = data_array(117) + 256 * data_array(118) + 65536 * data_array(119) + 256 * 65536 * data_array(120);
                        res5 = data_array(121) + 256 * data_array(122) + 65536 * data_array(123) + 256 * 65536 * data_array(124);
                        res6 = data_array(125) + 256 * data_array(126) + 65536 * data_array(127) + 256 * 65536 * data_array(128);
                        
                        if (res1 < 1)
                            res1 = 1;
                        end
                        
                        if (res2 < 1)
                            res2 = 1;
                        end
                        
                        if (res3 < 1)
                            res3 = 1;
                        end
                
                        if (res4 < 1)
                            res4 = 1;
                        end
                
                        if (res5 < 1)
                            res5 = 1;
                        end
                
                        if (res6 < 1)
                            res6 = 1;
                        end
                        
                        % Red and Infrared Parsing is Switched!!!
                        
                        C1IRn = C1R * 3. / (res1 * 4095.);
                        C2IRn = C2R * 3. / (res5 * 4095.);
                        C3IRn = C3R * 3. / (res5 * 4095.);
                        C4IRn = C4R * 3. / (res3 * 4095.);
                        C5IRn = C5R * 3. / (res1 * 4095.);
                        C6IRn = C6R * 3. / (res3 * 4095.);
                        C7IRn = C7R * 3. / (res5 * 4095.);
                        C8IRn = C8R * 3. / (res1 * 4095.);
                        C9IRn = C9R * 3. / (res3 * 4095.);
                        C10IRn = C10R * 3. / (res3 * 4095.);
                        C11IRn = C11R * 3. / (res3 * 4095.);
                        C12IRn = C12R * 3. / (res1 * 4095.);
                        C13IRn = C13R * 3. / (res1 * 4095.);
                        
                        C1Rn = C1IR * 3. / (res2 * 4095.);
                        C2Rn = C2IR * 3. / (res6 * 4095.);
                        C3Rn = C3IR * 3. / (res6 * 4095.);
                        C4Rn = C4IR * 3. / (res4 * 4095.);
                        C5Rn = C5IR * 3. / (res2 * 4095.);
                        C6Rn = C6IR * 3. / (res4 * 4095.);
                        C7Rn = C7IR * 3. / (res6 * 4095.);
                        C8Rn = C8IR * 3. / (res2 * 4095.);
                        C9Rn = C9IR * 3. / (res4 * 4095.);
                        C10Rn = C10IR * 3. / (res4 * 4095.);
                        C11Rn = C11IR * 3. / (res4 * 4095.);
                        C12Rn = C12IR * 3. / (res2 * 4095.);
                        C13Rn = C13IR * 3. / (res2 * 4095.);
%                         
%                         %% Write data to terminal
%                         fprintf("\n ==========================");
%                         fprintf("\n Time, %0.2f", toc);
%                         fprintf("\n C1R = %0.2f, C1IR = %0.2f", C1Rn, C1IRn);
%                         fprintf("\n C2R = %0.2f, C2IR = %0.2f", C2Rn, C2IRn);
%                         fprintf("\n C3R = %0.2f, C3IR = %0.2f", C3Rn, C3IRn);
%                         fprintf("\n C4R = %0.2f, C4IR = %0.2f", C4Rn, C4IRn);
%                         fprintf("\n C5R = %0.2f, C5IR = %0.2f", C5Rn, C5IRn);
%                         fprintf("\n C6R = %0.2f, C6IR = %0.2f", C6Rn, C6IRn);
%                         fprintf("\n C7R = %0.2f, C7IR = %0.2f", C7Rn, C7IRn);
%                         fprintf("\n C8R = %0.2f, C8IR = %0.2f", C8Rn, C8IRn);
%                         fprintf("\n C9R = %0.2f, C9IR = %0.2f", C9Rn, C9IRn);
%                         fprintf("\n C10R = %0.2f, C10IR = %0.2f", C10Rn, C10IRn);
%                         fprintf("\n C11R = %0.2f, C11IR = %0.2f", C11Rn, C11IRn);
%                         fprintf("\n C12R = %0.2f, C12IR = %0.2f", C12Rn, C12IRn);
%                         fprintf("\n C13R = %0.2f, C13IR = %0.2f", C13Rn, C13IRn);
                
                        if ((C1Rn < 2.9) && ...
                            (C1IRn < 2.9) && ...
                            (C2Rn < 2.9) && ...
                            (C2IRn < 2.9) && ...
                            (C3Rn < 2.9) && ...
                            (C3Rn < 2.9) && ...
                            (C4Rn < 2.9) && ...
                            (C4IRn < 2.9) && ...
                            (C5Rn < 2.9) && ...
                            (C5IRn < 2.9) && ...
                            (C6Rn < 2.9) && ...
                            (C6IRn < 2.9) && ...
                            (C7Rn < 2.9) && ...
                            (C7IRn < 2.9) && ...
                            (C8Rn < 2.9) && ...
                            (C8IRn < 2.9) && ...
                            (C9Rn < 2.9) && ...
                            (C9IRn < 2.9) && ...
                            (C10Rn < 2.9) && ...
                            (C10IRn < 2.9) && ...
                            (C11Rn < 2.9) && ...
                            (C11IRn < 2.9) && ...
                            (C12Rn < 2.9) && ...
                            (C12IRn < 2.9) && ...
                            (C13Rn < 2.9) && ...
                            (C13IRn < 2.9))
                            
                            % If no abnormally large data exists...then
                                    
                            %% Display Data on Plot Figure
                            app.time_array = [app.time_array toc];
                    
                            % Channel 1 Array
                            app.ch1R_array = [app.ch1R_array C1Rn];
                            app.ch1IR_array = [app.ch1IR_array C1IRn];
                    
                            % Channel 2 Array
                            app.ch2R_array = [app.ch2R_array C2Rn];
                            app.ch2IR_array = [app.ch2IR_array C2IRn];
                    
                            % Channel 3 Array
                            app.ch3R_array = [app.ch3R_array C3Rn];
                            app.ch3IR_array = [app.ch3IR_array C3IRn];
                    
                            % Channel 4 Array
                            app.ch4R_array = [app.ch4R_array C4Rn];
                            app.ch4IR_array = [app.ch4IR_array C4IRn];
                    
                            % Channel 5 Array
                            app.ch5R_array = [app.ch5R_array C5Rn];
                            app.ch5IR_array = [app.ch5IR_array C5IRn];
                    
                            % Channel 6 Array
                            app.ch6R_array = [app.ch6R_array C6Rn];
                            app.ch6IR_array = [app.ch6IR_array C6IRn];
                    
                            % Channel 7 Array
                            app.ch7R_array = [app.ch7R_array C7Rn];
                            app.ch7IR_array = [app.ch7IR_array C7IRn];
                    
                            % Channel 8 Array
                            app.ch8R_array = [app.ch8R_array C8Rn];
                            app.ch8IR_array = [app.ch8IR_array C8IRn];
                    
                            % Channel 9 Array
                            app.ch9R_array = [app.ch9R_array C9Rn];
                            app.ch9IR_array = [app.ch9IR_array C9IRn];
                    
                            % Channel 10 Array
                            app.ch10R_array = [app.ch10R_array C10Rn];
                            app.ch10IR_array = [app.ch10IR_array C10IRn];
                    
                            % Channel 11 Array
                            app.ch11R_array = [app.ch11R_array C11Rn];
                            app.ch11IR_array = [app.ch11IR_array C11IRn];
                    
                            % Channel 12 Array
                            app.ch12R_array = [app.ch12R_array C12Rn];
                            app.ch12IR_array = [app.ch12IR_array C12IRn];
                    
                            % Channel 13 Array
                            app.ch13R_array = [app.ch13R_array C13Rn];
                            app.ch13IR_array = [app.ch13IR_array C13IRn];
                            
                            % Highlight
                            
                            if strcmp(app.StimulusSwitch.Value, 'On')
                                app.phighlight = [app.phighlight 200];
                            else
                                app.phighlight = [app.phighlight 0];
                            end
                            
                            if length(app.time_array) > 50
                                
                                % Assign value based on drop down menu option
                                channel = app.ChannelSelectionDropDown.Value;
                                
                                if channel == '1'
                                    red = app.ch1R_array;
                                    IR = app.ch1IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 1';
                                elseif channel == '2'
                                    red = app.ch2R_array;
                                    IR = app.ch2IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 2';
                                elseif channel == '3'
                                    red = app.ch3R_array;
                                    IR = app.ch3IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 3';
                                elseif channel == '4'
                                    red = app.ch4R_array;
                                    IR = app.ch4IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 4';
                                elseif channel == '5'
                                    red = app.ch5R_array;
                                    IR = app.ch5IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 5';
                                elseif channel == '6'
                                    red = app.ch6R_array;
                                    IR = app.ch6IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 6';
                                elseif channel == '7'
                                    red = app.ch7R_array;
                                    IR = app.ch7IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 7';
                                elseif channel == '8'
                                    red = app.ch8R_array;
                                    IR = app.ch8IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 8';
                                elseif channel == '9'
                                    red = app.ch9R_array;
                                    IR = app.ch9IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 9';
                                elseif channel == '10'
                                    red = app.ch101R_array;
                                    IR = app.ch10IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 10';
                                elseif channel == '11'
                                    red = app.ch11R_array;
                                    IR = app.ch11IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 11';
                                elseif channel == '12'
                                    red = app.ch12R_array;
                                    IR = app.ch12IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 12';
                                else
                                    red = app.ch13R_array;
                                    IR = app.ch13IR_array;
                                    app.UIAxes.Title.String = 'Wireless fNIR Headband Data | Channel 13';
                                end
                                
                                %% Convert to Oxy and Deoxy Hb Values
                                                    
                                % Extinction Coefficients (mM, cm)^-1
                                d = 2.8;
                                e_Hb_740 = 1.1;
                                e_HbO2_740 = 0.45;
                                e_Hb_850 = 0.7;
                                e_HbO2_850 = 1.05;
                                                    
                                % Clean up and detrend data
                                curr_red = pipeline(app, app.time_array, red, 0.5);
                                curr_IR = pipeline(app, app.time_array, IR, 0.5);
                                
                                %Get DPF
                                DPF_740 = getDPF(app, 23, 740); %~218.5716
                                DPF_850 = getDPF(app, 23, 850); %~220.4823
                                
                                % Calculate long channel oxy and deoxy hemoglobin change in concentrations
                                app.dHb = (1/d)*(e_HbO2_850*curr_red*DPF_850 - e_HbO2_740*curr_IR*DPF_740);
                                app.dHbO2 = (1/d)*(-e_Hb_850*curr_red*DPF_850 + e_Hb_740*curr_IR*DPF_740);
                                                            
                                % Time Data Resizing (Just in case)
                                app.time_data = linspace(min(app.time_array), max(app.time_array), length(app.dHb));
                                
                                % Highlight Resizing (Just in case)
                                %app.phighlight = app.phighlight( 1:length(app.time_data));
                                
                                % Toggle indicator for new data
                                app.new_data = 1;
                                
                            end
                            
                        end
                        
                        %% Dump unnecessary data
                        if length(app.time_array) > 200
                            app.time_array = app.time_array((end-200):end);
                            app.ch1R_array = app.ch1R_array((end-200):end);
                            app.ch1IR_array = app.ch1IR_array((end-200):end);
                            app.ch2R_array = app.ch2R_array((end-200):end);
                            app.ch2IR_array = app.ch2IR_array((end-200):end);
                            app.ch3R_array = app.ch3R_array((end-200):end);
                            app.ch3IR_array = app.ch3IR_array((end-200):end);
                            app.ch4R_array = app.ch4R_array((end-200):end);
                            app.ch4IR_array = app.ch4IR_array((end-200):end);
                            app.ch5R_array = app.ch5R_array((end-200):end);
                            app.ch5IR_array = app.ch5IR_array((end-200):end);
                            app.ch6R_array = app.ch6R_array((end-200):end);
                            app.ch6IR_array = app.ch6IR_array((end-200):end);
                            app.ch7R_array = app.ch7R_array((end-200):end);
                            app.ch7IR_array = app.ch7IR_array((end-200):end);
                            app.ch8R_array = app.ch8R_array((end-200):end);
                            app.ch8IR_array = app.ch8IR_array((end-200):end);
                            app.ch9R_array = app.ch9R_array((end-200):end);
                            app.ch9IR_array = app.ch9IR_array((end-200):end);
                            app.ch10R_array = app.ch10R_array((end-200):end);
                            app.ch10IR_array = app.ch10IR_array((end-200):end);
                            app.ch11R_array = app.ch11R_array((end-200):end);
                            app.ch11IR_array = app.ch11IR_array((end-200):end);
                            app.ch12R_array = app.ch12R_array((end-200):end);
                            app.ch12IR_array = app.ch12IR_array((end-200):end);
                            app.ch13R_array = app.ch13R_array((end-200):end);
                            app.ch13IR_array = app.ch13IR_array((end-200):end);
                            app.phighlight = app.phighlight((end-200):end);
                        end
                    end
                    
%                     catch
%                         
%                         if ~strcmp(app.b.Status, 'closed')
%                             disp("Bluetooth data reception error!!");
%                         else
%                             break;
%                         end
%                     end
                    
                end
            else
                disp(value);
                disp("============================================================")
                disp("Closing Bluetooth Connection...")
                disp(app.b);
                fclose(app.b);
            end
        end

        % Value changed function: StimulusSwitch
        function StimulusSwitchValueChanged(app, event)
            value = app.StimulusSwitch.Value;
            if strcmp(value, 'On')
                disp('Stimulus ON')
                app.stimulus = 1;
                disp(app.stimulus)
            else
                disp('Stimulus OFF')
                app.stimulus = 0;
                disp(app.stimulus)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StimulusSwitchLabel = uilabel(app.UIFigure);
            app.StimulusSwitchLabel.Text = 'Stimulus';
            app.StimulusSwitch = uiswitch(app.UIFigure, 'slider');
            app.StimulusSwitch.ValueChangedFcn = createCallbackFcn(app, @StimulusSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wireless fNIR Headband Data')
            xlabel(app.UIAxes, 'Time Elapsed (s)')
            ylabel(app.UIAxes, '\Delta Concentration (a.u.)')
            app.BowdenBiomedicalOpticsLaboratoryLabel = uilabel(app.UIFigure);
            app.BowdenBiomedicalOpticsLaboratoryLabel.Text = 'Bowden Biomedical Optics Laboratory';
            app.StreamDataSwitchLabel = uilabel(app.UIFigure);
            app.StreamDataSwitchLabel.Text = 'Stream Data';
            app.StreamDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.StreamDataSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamDataSwitchValueChanged, true);
            app.ChannelSelectionDropDownLabel = uilabel(app.UIFigure);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.UIFigure);
            app.ChannelSelectionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', ''};
            app.ChannelSelectionDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end